classdef Xsens_APP_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExportButton          matlab.ui.control.Button
        Tree                  matlab.ui.container.Tree
        JointDataNode         matlab.ui.container.TreeNode
        jL5S1Node             matlab.ui.container.TreeNode
        jL4L3Node             matlab.ui.container.TreeNode
        jL1T12Node            matlab.ui.container.TreeNode
        jT9T8Node             matlab.ui.container.TreeNode
        jT1C7Node             matlab.ui.container.TreeNode
        jRightT4ShoulderNode  matlab.ui.container.TreeNode
        jRightShoulderNode    matlab.ui.container.TreeNode
        jRightElbowNode       matlab.ui.container.TreeNode
        jRightWristNode       matlab.ui.container.TreeNode
        jLeftT4ShoulderNode   matlab.ui.container.TreeNode
        jLeftShoulderNode     matlab.ui.container.TreeNode
        jLeftElbowNode        matlab.ui.container.TreeNode
        jLeftWristNode        matlab.ui.container.TreeNode
        jRightHipNode         matlab.ui.container.TreeNode
        jRightKneeNode        matlab.ui.container.TreeNode
        jRightAnkleNode       matlab.ui.container.TreeNode
        jRightBallFootNode    matlab.ui.container.TreeNode
        jLeftHipNode          matlab.ui.container.TreeNode
        jLeftKneeNode         matlab.ui.container.TreeNode
        jLeftAnkleNode        matlab.ui.container.TreeNode
        jLeftBallFootNode     matlab.ui.container.TreeNode
        Tree2                 matlab.ui.container.Tree
        WalkingNode_2         matlab.ui.container.TreeNode
        HS_TONode_2           matlab.ui.container.TreeNode
        ACCNode_2             matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        tree; files;str;JData;JData_x;JData_y;JData_z; prompt;fileList; % Description
    end
    
    methods (Access = private)
        
        function LoadData(app)
          
            app.prompt = questdlg('How do you want to import your data?',...
                'GUI','Import Folder','Import File','Import File');
            
            switch app.prompt
                
                case 'Import Folder'
                
                uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter'); % Set directory
                app.files = dir('*.mvnx');
                app.fileList = dir('*.*');
                
                    for i = 1:length(app.files)
                        app.tree.data(i) = load_mvnx(app.files(i).name);
                    end
                    
                case 'Import File'
                
                    uigetdir('C:\Users\Jessica S. Gilliam\Documents\MATLAB\ShepherdCenter'); % Set directory
                    app.files = uigetfile('*.mvnx');
                    app.fileList = dir('*.*');
                    app.tree.data = load_mvnx(app.files);
                
            end
            
                     
        end
        
        
        function ExportData(app)
           
%             if app.prompt 
%                 
%                 for i = 1:length(app.files)
%                     for j = 1:22
%                         for s = 1:23
%                             Table_S = app.tree.data(i).segmentData(s).orientation;
%                         end
%                         Table_J = app.tree.data(i).jointData(j).jointAngle;
%                     end
%                                   
%                        
%                     y_1 = num2str(i);
%                     
%                     filename = 'Participant_JointData.xls';
%                     writematrix(Table_J, filename,'Sheet',y_1);
%                 end
%             else
%                 
%                 filename = 'Participant_JointData.xls';
%                 writematrix(app.tree.data,filename,'Sheet',y_1);
%             end
        end
        
%         function Analyze(app)
            
%             promptn = input('Select Analysis Type:[JointAngle(1)] ');
%                     
%                     if promptn
%                         
%                         app.str = input('What joint are you looking at? Write specific Xsens joint name: ','s');
%                                                 
%                         C = {'jL5S1','jL4L3','jL1T12','jT9T8','jT1C7','jC1Head','jRightT4Shoulder','jRightShoulder',...
%                         'jRightElbow','jRightWrist','jLeftT4Shoulder','jLeftShoulder','jLeftElbow','jLeftWrist',...
%                         'jRightHip','jRightKnee','jRightAnkle','jRightBallFoot','jLeftHip','jLeftKnee','jLeftAnkle',...
%                         'jLeftBallFoot'};
%                     
%                         idx = strcmpi(app.str, C);
%                         [r,c] = find(idx);
%                         
%                         prompt2 = input('What Axis? X(2), Y(1), Z(0): ');
%                                                 
%                         prompt3 = input('What Participant are you analyzing? [ID num]: ');
%                         set = [1,5,6]; % this can be changed here
%                         [a,b] = find(set == prompt3);
%                         app.JData = app.tree.data(b).jointData(c).jointAngle(:,prompt2);
%                                               
%                         
%                     end
            
%         end
        
        function AnalyzeData(app)
            app.Tree.Multiselect = 'on';
            % once selected, what do we want to do next?
            % could lay out 1:22 file path to data?
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
            app.Tree.Multiselect = 'on';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            ExportData(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
          
            defaultValue = {'1','1','1','1','1'};
            titleBar = 'Values';
            userPrompt = {'Enter Participant ID#: ', 'Analyze X? Y[1]/N[0]: ','Analyze Y? Y[1]/N[0]: ','Analyze Z? Y[1]/N[0]: '};
            caUserInput = inputdlg(userPrompt, titleBar, 1, defaultValue);
                if isempty(caUserInput),return,end % Bail out if they clicked Cancel.
                    % Convert to floating point from string.
                   
                      %% Create a drop down menu in pop up prompt! Maybe two seperate ones for xyz and participant ID?  
%                         
%                         name = app.fileList(1).name;
%                         sprintf(name,p,t,d) 
                    usersValue1 = str2double(caUserInput{1});
                    usersValue2 = str2double(caUserInput{2});
                    usersValue3 = str2double(caUserInput{3});
                    usersValue4 = str2double(caUserInput{4});
                    %app.ParticipantIDEditField.Value = usersValue1;
                    % Check for a valid integer.
                    if isnan(usersValue1)
                        % They didn't enter a number.  
                        % They clicked Cancel, or entered a character, symbols, or something else not allowed.
                        % usersValue1 = defaultValue;
                        message = sprintf('Must be an integer.\nI will use %d and continue.', usersValue1);
                        uiwait(warndlg(message));
                    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
      
            app.Tree.Multiselect = 'on';
            Nodes = event.SelectedNodes;
            switch Nodes
                case app.jL5S1Node
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(1).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(1).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(1).jointAngle(:,3);
                            end
                        end
                    end
                case app.jL4L3Node
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(2).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(2).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(2).jointAngle(:,3);
                            end
                        end
                    end
                case app.jL1T12Node
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(3).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(3).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(3).jointAngle(:,3);
                            end
                        end
                    end
                case app.jT9T8Node
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(4).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(4).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(4).jointAngle(:,3);
                            end
                        end
                    end
                case app.jT1C7Node
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(5).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(5).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(5).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightT4ShoulderNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(6).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(6).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(6).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightShoulderNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(7).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(7).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(7).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightElbowNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(8).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(8).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(8).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightWristNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(9).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(9).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(9).jointAngle(:,3);
                            end
                        end
                    end
                case app.jLeftT4ShoulderNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(10).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(11).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(12).jointAngle(:,3);
                            end
                        end
                    end
                case app.jLeftShoulderNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(13).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(13).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(13).jointAngle(:,3);
                            end
                        end
                    end
                case app.jLeftElbowNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(14).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(14).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(14).jointAngle(:,3);
                            end
                        end
                    end
                case app.jLeftWristNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(15).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(15).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(15).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightHipNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(16).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(16).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(16).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightKneeNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(17).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(17).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(17).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightAnkleNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(18).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(18).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(18).jointAngle(:,3);
                            end
                        end
                    end
                case app.jRightBallFootNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(19).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(19).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(19).jointAngle(:,3);
                            end
                        end
                    end
                case app.jLeftHipNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(20).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(20).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(20).jointAngle(:,3);
                            end
                        end
                    end
            
                case app.jLeftKneeNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(21).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(21).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(21).jointAngle(:,3);
                            end
                        end
                    end
                    
                case app.jLeftAnkleNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(22).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(22).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(22).jointAngle(:,3);
                            end
                        end
                    end
                     
                case app.jLeftBallFootNode
                    if usersValue2
                        app.JData_x = app.tree.data(usersValue1).jointData(23).jointAngle(:,1);
                        if usersValue3
                            app.JData_y = app.tree.data(usersValue1).jointData(23).jointAngle(:,2);
                            if usersValue4
                                app.JData_z = app.tree.data(usersValue1).jointData(23).jointAngle(:,3);
                            end
                        end
                    end
            end
                      
                    T = table(app.JData_x, app.JData_y, app.JData_z);
                    T.Properties.VariableNames = {'X','Y','Z'};
                    fig = uifigure('Name',sprintf('Participant_%d',usersValue1));
                    fig.Position = [140 80 900 600];
                    
                    ax = uiaxes(fig);
                    ax2 = uiaxes(fig);
                    ax3 = uiaxes(fig);
                    ax.Position = [400 10 500 200];
                    ax2.Position = [400 200 500 200];
                    ax3.Position = [400 400 500 200];
                    ax.YLabel.String = '[X]';
                    ax2.YLabel.String = '[Y]';
                    ax3.YLabel.String = '[Z]';
                    y3 = app.JData_z;
                    y2 = app.JData_y;
                    y1 = app.JData_x;
                    time = str2double({app.tree.data(usersValue1).frame.time});
                    time = (time)/1000; 
                    plot(ax,time,y1)
                    plot(ax2,time,y2)
                    plot(ax3,time,y3)
                    
                    uit = uitable(fig,'Data',T);
                    uit.Position = [10 10 300 550];
                    uit.RowName = 'numbered';
                    fig.AutoResizeChildren = 'on';


        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            
            defaultValue = {'1'};
            titleBar = 'Values';
            userPrompt = {'Enter Participant ID#: '};
            caUserInput = inputdlg(userPrompt, titleBar, 1, defaultValue);
                if isempty(caUserInput),return,end % Bail out if they clicked Cancel.
                    % Convert to floating point from string.
                    usersValue1 = str2double(caUserInput{1});
                    
                    % Check for a valid integer.
                    if isnan(usersValue1)
                        % They didn't enter a number.  
                        % They clicked Cancel, or entered a character, symbols, or something else not allowed.
                        % usersValue1 = defaultValue;
                        message = sprintf('Must be an integer.\nI will use %d and continue.', usersValue1);
                        uiwait(warndlg(message));
                    end
            
            
          Pelvis_x = app.tree.data(usersValue1).segmentData(1).position(:,1);
          R_Foot_x = app.tree.data(usersValue1).segmentData(18).position(:,1);
          R_Toe_x = app.tree.data(usersValue1).segmentData(19).position(:,1);
          L_Foot_x = app.tree.data(usersValue1).segmentData(22).position(:,1);
          L_Toe_x = app.tree.data(usersValue1).segmentData(23).position(:,1);
          time = str2double({app.tree.data(usersValue1).frame.time});
          time = (time)/1000;
          index = 1:length(time);
          
          Nodes2 = selectedNodes;
            switch Nodes2
                case app.HS_TONode_2
                    HS_r = islocalmax((R_Foot_x - Pelvis_x),'MinProminence',.1);
                    HS_l = islocalmax((L_Foot_x - Pelvis_x),'MinProminence',.1);
                    
                    HS_r_time = time(HS_r);
                    HS_l_time = time(HS_l);
                    HS_r_index = index(HS_r);
                    HS_l_index = index(HS_l);
                  
                    TO_r = islocalmin((R_Toe_x - Pelvis_x),'Minprominence',.1);
                    TO_l = islocalmin((L_Toe_x - Pelvis_x),'MinProminence',.1);
                    
                    TO_r_time = time(TO_r);
                    TO_l_time = time(TO_l);
                    TO_r_index = index(TO_r);
                    TO_l_index = index(TO_l);
                    
                    while length(HS_l_time) ~= length(TO_l_time)
                        if length(HS_l_time) > length(TO_l_time)
                            HS_l_time(end) = [];
                            HS_l_index(end) = [];   

                        elseif length(TO_l_time) > length(HS_l_time)
                            TO_l_time(end) = [];
                            TO_l_index(end) = [];
                        end
                    end
                    
                    while length(HS_r_time) ~= length(TO_r_time)
                        if length(HS_r_time) > length(TO_r_time)
                            HS_r_time(end) = [];
                            HS_r_index(end) = [];   

                        elseif length(TO_r_time) > length(HS_r_time)
                            TO_r_time(end) = [];
                            TO_r_index(end) = [];
                        end
                    end
                    
                    T = table(HS_l_time', HS_l_index', HS_r_time', HS_r_index');
                    T.Properties.VariableNames = {'Left Heel Strike time','Left Heel Strike index',...
                        'Right Heel Strike time','Right Heel Strike index'};
                    fig = uifigure('Name',sprintf('Participant_%d',usersValue1));
                    fig.Position = [140 80 900 600];
                    uit = uitable(fig,'Data',T);
                    uit.Position = [50 50 600 450];
                    uit.RowName = 'numbered';
                    fig.AutoResizeChildren = 'on';
                    
                case app.ACCNode_2
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.JointDataNode = uitreenode(app.Tree);
            app.JointDataNode.Text = 'Joint Data';
            app.jL5S1Node = uitreenode(app.JointDataNode);
            app.jL5S1Node.Text = 'jL5S1';
            app.jL4L3Node = uitreenode(app.JointDataNode);
            app.jL4L3Node.Text = 'jL4L3';
            app.jL1T12Node = uitreenode(app.JointDataNode);
            app.jL1T12Node.Text = 'jL1T12';
            app.jT9T8Node = uitreenode(app.JointDataNode);
            app.jT9T8Node.Text = 'jT9T8';
            app.jT1C7Node = uitreenode(app.JointDataNode);
            app.jT1C7Node.Text = 'jT1C7';
            app.jRightT4ShoulderNode = uitreenode(app.JointDataNode);
            app.jRightT4ShoulderNode.Text = 'jRightT4Shoulder';
            app.jRightShoulderNode = uitreenode(app.JointDataNode);
            app.jRightShoulderNode.Text = 'jRightShoulder';
            app.jRightElbowNode = uitreenode(app.JointDataNode);
            app.jRightElbowNode.Text = 'jRightElbow';
            app.jRightWristNode = uitreenode(app.JointDataNode);
            app.jRightWristNode.Text = 'jRightWrist';
            app.jLeftT4ShoulderNode = uitreenode(app.JointDataNode);
            app.jLeftT4ShoulderNode.Text = 'jLeftT4Shoulder';
            app.jLeftShoulderNode = uitreenode(app.JointDataNode);
            app.jLeftShoulderNode.Text = 'jLeftShoulder';
            app.jLeftElbowNode = uitreenode(app.JointDataNode);
            app.jLeftElbowNode.Text = 'jLeftElbow';
            app.jLeftWristNode = uitreenode(app.JointDataNode);
            app.jLeftWristNode.Text = 'jLeftWrist';
            app.jRightHipNode = uitreenode(app.JointDataNode);
            app.jRightHipNode.Text = 'jRightHip';
            app.jRightKneeNode = uitreenode(app.JointDataNode);
            app.jRightKneeNode.Text = 'jRightKnee';
            app.jRightAnkleNode = uitreenode(app.JointDataNode);
            app.jRightAnkleNode.Text = 'jRightAnkle';
            app.jRightBallFootNode = uitreenode(app.JointDataNode);
            app.jRightBallFootNode.Text = 'jRightBallFoot';
            app.jLeftHipNode = uitreenode(app.JointDataNode);
            app.jLeftHipNode.Text = 'jLeftHip';
            app.jLeftKneeNode = uitreenode(app.JointDataNode);
            app.jLeftKneeNode.Text = 'jLeftKnee';
            app.jLeftAnkleNode = uitreenode(app.JointDataNode);
            app.jLeftAnkleNode.Text = 'jLeftAnkle';
            app.jLeftBallFootNode = uitreenode(app.JointDataNode);
            app.jLeftBallFootNode.Text = 'jLeftBallFoot';
            app.Tree2 = uitree(app.UIFigure);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.WalkingNode_2 = uitreenode(app.Tree2);
            app.WalkingNode_2.Text = 'Walking';
            app.HS_TONode_2 = uitreenode(app.WalkingNode_2);
            app.HS_TONode_2.Text = 'HS_TO';
            app.ACCNode_2 = uitreenode(app.WalkingNode_2);
            app.ACCNode_2.Text = 'ACC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end